classdef Go < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        InterfaceTab              matlab.ui.container.Tab
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        RESETGAMEButton           matlab.ui.control.Button
        VerdesPanel               matlab.ui.container.Panel
        PasarButton               matlab.ui.control.Button
        RendirseButton            matlab.ui.control.Button
        JugarButton               matlab.ui.control.Button
        CapturasEditField_2Label  matlab.ui.control.Label
        CapturasEditField_2       matlab.ui.control.NumericEditField
        DropDown                  matlab.ui.control.DropDown
        NaranjasPanel             matlab.ui.container.Panel
        EditField_2               matlab.ui.control.EditField
        CapturasEditFieldLabel    matlab.ui.control.Label
        CapturasEditField         matlab.ui.control.NumericEditField
        TextArea                  matlab.ui.control.TextArea
        CapturaButton             matlab.ui.control.Button
        EditField_3               matlab.ui.control.EditField
        CALIBRATECAMERAButton     matlab.ui.control.Button
        ColorDetectionTab         matlab.ui.container.Tab
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes2_2                 matlab.ui.control.UIAxes
        SerialandCalibrationTab   matlab.ui.container.Tab
        ComunicacinPanel          matlab.ui.container.Panel
        PuertoDropDownLabel       matlab.ui.control.Label
        Puerto                    matlab.ui.control.DropDown
        ConectarButton            matlab.ui.control.Button
        DesconectarButton         matlab.ui.control.Button
        RButton                   matlab.ui.control.Button
        NEXTButton                matlab.ui.control.Button
        TextArea_2                matlab.ui.control.TextArea
        BaudrateDropDownLabel     matlab.ui.control.Label
        Baudrate                  matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        M = zeros(5,5); % Matriz de posiciones
        N = zeros(5,5);
        S = zeros(5,5);
        T = zeros(5,5);
        
        histo = strings(0); % Historia de la partida
        turn = 1;
        passWhite = 0;
        passBlack = 0;
        
        black = 0;
        white = 0;
        vid = 0;
        my_tform = 0;
        
        ardu = 0;
    end
    
    methods (Access = private)
        
        function board(app)
            hold(app.UIAxes_2,'off');
            for i=1:5
                plot(app.UIAxes_2,[1 5],[i i],'-k');
                hold(app.UIAxes_2,'on');
                plot(app.UIAxes_2,[i i],[1 5],'k');
            end
            plot(app.UIAxes_2,[0 0],[0 6],'-w');
            plot(app.UIAxes_2,[6 0],[6 6],'-w');
            plot(app.UIAxes_2,[6 6],[0 6],'-w');
            plot(app.UIAxes_2,[0 6],[0 0],'-w');
            for i=1:5
                for j=1:5
                    if app.M(i,j) == 1
                        plot(app.UIAxes_2,i,j,'.g','MarkerSize',50);
                    elseif app.M(i,j) == -1
                        plot(app.UIAxes_2,i,j,'.r','MarkerSize',50);
                    end
                end
            end
            
            axis(app.UIAxes_2,'equal');
            
            text(app.UIAxes_2,1,1,'A','HorizontalAlignment','center','VerticalAlignment','top');
            text(app.UIAxes_2,2,1,'B','HorizontalAlignment','center','VerticalAlignment','top');
            text(app.UIAxes_2,3,1,'C','HorizontalAlignment','center','VerticalAlignment','top');
            text(app.UIAxes_2,4,1,'D','HorizontalAlignment','center','VerticalAlignment','top');
            text(app.UIAxes_2,5,1,'E','HorizontalAlignment','center','VerticalAlignment','top');
            
            text(app.UIAxes_2,1,1,'1','HorizontalAlignment','right','VerticalAlignment','middle');
            text(app.UIAxes_2,1,2,'2','HorizontalAlignment','right','VerticalAlignment','middle');
            text(app.UIAxes_2,1,3,'3','HorizontalAlignment','right','VerticalAlignment','middle');
            text(app.UIAxes_2,1,4,'4','HorizontalAlignment','right','VerticalAlignment','middle');
            text(app.UIAxes_2,1,5,'5','HorizontalAlignment','right','VerticalAlignment','middle');
            
            set(app.UIAxes_2,'visible','off')
        end
        
        function createInputFile(app,check,endgame)
            f = fopen("input.txt","w");
            fprintf(f,"boardsize 5\n");
            fprintf(f,"clear_board\n");
            
            for i=1:length(app.histo)
                fprintf(f,"play %s\n",app.histo(i));
            end
            if check == false
                fprintf(f,"genmove white\n");
                fprintf(f,"showboard\n");
            end
            if endgame == true
                fprintf(f,"final_score\n");
            end
            fclose(f);
        end
        
        function showBoard(app)
            str = sscanf(fileread('output.txt'),"%s");
            
            expression = '([.+OX]{5})';
            matchStr = cell2mat(regexp(str,expression,'match'));
            
            Mstr = [matchStr(1:5); matchStr(6:10); matchStr(11:15); matchStr(16:20); matchStr(21:25);];
            
            app.M = zeros(5,5);
            for i=1:5
                for j=1:5
                    if Mstr(i,j) == 'X'
                        app.M(i,j) = 1;
                    elseif Mstr(i,j) == 'O'
                        app.M(i,j) = -1;
                    end
                end
            end
            
            app.M = rot90(app.M,3);
            
            
            expression = 'hascaptured(\d+)';
            matchStr = regexp(str,expression,'tokens');
            app.CapturasEditField_2.Value = str2double(cell2mat(matchStr{2}));
            app.CapturasEditField.Value   = str2double(cell2mat(matchStr{1}));
        end
        
        function [passed,resign] = playAI(app)
            passed = false; resign = false;
            
            delete output.txt;
            system("gnugo --mode gtp --gtp-input input.txt >> output.txt");
            str = sscanf(fileread('output.txt'),"%s")
            
            if str(length(app.histo) + 4) ~= 'r' && str(length(app.histo) + 4) ~= 'P'
                i = str(length(app.histo) + 4);
                j = str(length(app.histo) + 5);
                app.histo(end+1) = sprintf("white %c%c", i, j);
                app.EditField_2.Value = sprintf("%c%c",i,j);
            elseif str(length(app.histo) + 4) == 'r'
                resign = true;
            elseif str(length(app.histo) + 4) == 'P'
                passed = true;
            end
            
            
        end
        
        function ilegal = checkIlegal(app)
            ilegal = false;
            
            delete output.txt;
            system("gnugo --mode gtp --gtp-input input.txt >> output.txt");
            str = sscanf(fileread('output.txt'),"%s");
            if  str(length(app.histo) + 2) == '?'
                ilegal = true;
            end
            
        end
        
        function compute(app)
            createInputFile(app,false,false);
            [passed,resign] = playAI(app);
            if passed
                app.EditField_2.Value = "PASS";
                if app.passBlack == 1
                    endGame(app)
                end
            elseif resign
                app.RendirseButton_3.BackgroundColor = [1 0 0];
                pause(1);
                app.RendirseButton_3.BackgroundColor = [0.96 0.96 0.96];
                app.TextArea.Value = "NARANJA SE RINDEN";
            else
                app.passWhite = 0;
            end
            showBoard(app);
            board(app);
        end
        
        function endGame(app)
            createInputFile(app,false,true);
            delete output.txt;
            system("gnugo --mode gtp --gtp-input input.txt >> output.txt");
            str = sscanf(fileread('output.txt'),"%s");
            expression ='([WB])([+\d\.]+)$';
            matchStr = regexp(str,expression,'tokens');
            if matchStr{1}{1} == 'W'
                app.TextArea.Value = sprintf("GANAN NARANJAS %s",matchStr{1}{2});
            else
                app.TextArea.Value = sprintf("GANAN VERDES %s",matchStr{1}{2});
            end
            
            pause(3);
        end
        
        
        function M = getMatrix(app,J)
            lx = linspace(639/10,639*9/10,5);
            ly = linspace(480/10,480*9/10,5);
           [X,Y] = meshgrid(lx,ly);
            
            ob = 800;
            %% Detectar colores
            blackBand = J(:,:,1);
            whiteBand =  J(:,:,2);
            blueBand =  J(:,:,3);
            
            %% NEGRAS
            blackMask = (blackBand >= app.black.RL) & (blackBand <= app.black.RH);
            whiteMask = (whiteBand >= app.black.GL) & (whiteBand <= app.black.GH);
            blueMask = (blueBand >= app.black.BL) & (blueBand <= app.black.BH);
            objectsMask =blackMask & whiteMask & blueMask;
            
            % Detectar objetos
            % Eliminar bloques de color pequeños
            openBlocks = bwareaopen(objectsMask, ob);
            
            % figure
            % subplot(2,1,1);
            imshow(objectsMask,'Parent',app.UIAxes2_2); hold(app.UIAxes2_2,'on');
             plot(app.UIAxes2_2,X,Y,'.c');
            % Reconocer bloques y guardar posición y controno
            blackStats = regionprops(openBlocks, 'BoundingBox', 'Centroid');
             for object = 1:length(blackStats)
                 bb = blackStats(object).BoundingBox;
                 bc = blackStats(object).Centroid;
                 
                 rectangle(app.UIAxes2_2,'Position', bb, 'EdgeColor', 'r', 'LineWidth', 2);
                 plot(app.UIAxes2_2,bc(1),bc(2), '-m+');
             end
            
            %% BLANCAS
            
            blackMask = (blackBand >= app.white.RL) & (blackBand <= app.white.RH);
            whiteMask = (whiteBand >= app.white.GL) & (whiteBand <= app.white.GH);
            blueMask = (blueBand >= app.white.BL) & (blueBand <= app.white.BH);
            objectsMask =blackMask & whiteMask & blueMask;
            
            %subplot(2,1,2);
            imshow(objectsMask,'Parent',app.UIAxes2); hold(app.UIAxes2,'on');
            plot(app.UIAxes2,X,Y,'.c');
            
            % Detectar objetos
            % Eliminar bloques de color pequeños
            openBlocks = bwareaopen(objectsMask, ob);
            
            % Reconocer bloques y guardar posición y controno
            whiteStats = regionprops(openBlocks, 'BoundingBox', 'Centroid');
             for object = 1:length(whiteStats)
                 bb = whiteStats(object).BoundingBox;
                 bc = whiteStats(object).Centroid;
                 
                 rectangle(app.UIAxes2,'Position', bb, 'EdgeColor', 'g', 'LineWidth', 2);
                 plot(app.UIAxes2,bc(1),bc(2), '-m+');
             end
            
            
            %% Generar matriz
            M = zeros(5,5);
            
            
            for object = 1:length(blackStats)
                bc = blackStats(object).Centroid;
                [~,i] = min(abs(bc(1)-lx));
                [~,j] = min(abs(bc(2)-ly));
                M(i,j) = 1;
            end
            
            
            for object = 1:length(whiteStats)
                bc = whiteStats(object).Centroid;
                [~,i] = min(abs(bc(1)-lx));
                [~,j] = min(abs(bc(2)-ly));
                M(i,j) = -1;
            end
            M = fliplr(M);
            
            
        end
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Puerto.Items=seriallist;
            
            app.Baudrate.Items = {'9600','115200'};
            app.Baudrate.Value = {'115200'};
        end

        % Button pushed function: RESETGAMEButton
        function RESETGAMEButtonPushed(app, event)
            app.M = zeros(5,5); % Matriz de posiciones
            app.histo = strings(0,0); % Historia de la partida
            app.turn = 1;
            board(app);
            app.TextArea.Value = "";
            
        end

        % Button pushed function: JugarButton
        function JugarButtonPushed(app, event)
            if app.turn == 1
                app.turn = 0;
                app.TextArea.Value = "";
                
                app.histo(end+1) = sprintf("black %s", app.DropDown.Value);
                createInputFile(app,true,false);
                ilegal = checkIlegal(app);
                
                if ilegal
                    app.histo(end) = [];
                    app.turn = 1;
                    app.TextArea.Value = "ILEGAL";
                    return;
                end
                app.passBlack = 0;
                compute(app)
                app.turn = 1;
            end
        end

        % Button pushed function: PasarButton
        function PasarButtonPushed(app, event)
            if app.turn == 1
                app.turn = 0;
                app.passBlack = 1;
                if app.passWhite == 1
                    endGame(app);
                end
                compute(app);
                app.turn = 1;
            end
        end

        % Button pushed function: RendirseButton
        function RendirseButtonPushed(app, event)
            app.TextArea.Value = "NARANJAS SE RINDEN";
        end

        % Button pushed function: CapturaButton
        function CapturaButtonPushed(app, event)

            
            im =snapshot(app.vid);
            
            J = imwarp(im,app.my_tform,'cubic','OutputView', imref2d( size(im) ));
            imshow(J,'Parent',app.UIAxes);
            
 
           app.S = getMatrix(app,J);
           app.S
           
           if sum(sum(app.T ~= 0))
                A = (app.S == 1) & (app.T == 0);
                [row,col] = find(A == 1)
                c = 'ABCDE';
                if length(row) ~= 0 && length(col) ~= 0
                    app.EditField_3.Value = sprintf("N to %c%d\n",c(row),col);
                    
                   if app.turn == 1
                    app.turn = 0;
                    app.TextArea.Value = "";
                
                    app.histo(end+1) = sprintf("black %c%d", c(row),col);
                    app.histo
                    createInputFile(app,true,false);
                    ilegal = checkIlegal(app);
                
                    if ilegal
                        app.histo(end) = [];
                        app.turn = 1;
                        app.TextArea.Value = "ILEGAL";
                        return;
                    end
                    app.passBlack = 0;
                    compute(app);
                    app.turn = 1;
                    end
               end  
           end
           
           app.T = app.S;


        end

        % Button pushed function: CALIBRATECAMERAButton
        function CALIBRATECAMERAButtonPushed(app, event)
            
            if isnumeric(app.vid)
                % Buscar camaras
                cameras = strcmp('Webcam C170: Webcam C170',webcamlist);
           
                % Buscar camara1
                for i=1:length(webcamlist)
                    if cameras(i)
                        app.vid = webcam(i);
                        break;
                    end
                end
            end     
            
            preview( app.vid);
            
            tic
            while toc < 2
            end
            
            im =snapshot(app.vid);
            
            figure
            imshow(im);
            title('Selecciona las esquinas y pulsa enter');
            [x,y] = getpts;
            a = [x y];
            title('');
            b = (  ((a - mean(a)) > 0) .* [639 479]  ) +1 ; % Maximizar
            app.my_tform    = fitgeotrans(a,b,'projective'); % Matriz de transformación
            
            J = imwarp(im,app.my_tform,'cubic','OutputView', imref2d( size(im) ));
            imshow(J,'Parent',app.UIAxes);


            imshow(J);
            
            title('Selecciona ficha verde y pulsa enter');
            [x,y] = getpts;
            r = impixel(J,round(x),round(y));
            threshold = 10;
            app.black = struct('RL',r(1)-threshold,'RH',r(1)+threshold,...
                'GL',r(2)-threshold,'GH',r(2)+threshold,...
                'BL',r(3)-threshold,'BH',r(3)+threshold);
            title('Selecciona algo naranja y pulsa enter');
            [x,y] = getpts;
            v = impixel(J,round(x),round(y));
            app.white = struct('RL',v(1)-threshold,'RH',v(1)+threshold,...
                'GL',v(2)-threshold,'GH',v(2)+threshold,...
                'BL',v(3)-threshold,'BH',v(3)+threshold);
            close all

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.arduino);
            delete(app.vid);
            clear app.vid
            delete(app)
            clear app
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            app.arduino=serial(app.Puerto.Value,'BaudRate',str2double(app.Baudrate.Value));
            app.arduino.Terminator = 'LF';
            app.arduino.BytesAvailableFcnMode =  'terminator';
            app.arduino.BytesAvailableFcn = @(~,~) app.serialEvent;
            fopen(app.arduino);

            set(app.ConectarButton,'BackgroundColor',[0.6 1 0.6]);
            set(app.DesconectarButton,'BackgroundColor',[0.96,0.96,0.96]);
            pause(2);
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
                        app.Puerto.Items=seriallist;
        end

        % Button pushed function: DesconectarButton
        function DesconectarButtonPushed(app, event)
                            fclose(app.arduino);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InterfaceTab = uitab(app.TabGroup);
            app.InterfaceTab.Title = 'Interface';
            app.UIAxes = uiaxes(app.InterfaceTab);
            app.UIAxes_2 = uiaxes(app.InterfaceTab);
            app.RESETGAMEButton = uibutton(app.InterfaceTab, 'push');
            app.RESETGAMEButton.ButtonPushedFcn = createCallbackFcn(app, @RESETGAMEButtonPushed, true);
            app.RESETGAMEButton.Text = 'RESET GAME';
            app.VerdesPanel = uipanel(app.InterfaceTab);
            app.VerdesPanel.Title = 'Verdes';
            app.PasarButton = uibutton(app.VerdesPanel, 'push');
            app.PasarButton.ButtonPushedFcn = createCallbackFcn(app, @PasarButtonPushed, true);
            app.PasarButton.Text = 'Pasar';
            app.RendirseButton = uibutton(app.VerdesPanel, 'push');
            app.RendirseButton.ButtonPushedFcn = createCallbackFcn(app, @RendirseButtonPushed, true);
            app.RendirseButton.Text = 'Rendirse';
            app.JugarButton = uibutton(app.VerdesPanel, 'push');
            app.JugarButton.ButtonPushedFcn = createCallbackFcn(app, @JugarButtonPushed, true);
            app.JugarButton.Text = 'Jugar';
            app.CapturasEditField_2Label = uilabel(app.VerdesPanel);
            app.CapturasEditField_2Label.Text = 'Capturas';
            app.CapturasEditField_2 = uieditfield(app.VerdesPanel, 'numeric');
            app.CapturasEditField_2.ValueDisplayFormat = '%.0f';
            app.DropDown = uidropdown(app.VerdesPanel);
            app.DropDown.Items = {'A1', 'A2', 'A3', 'A4', 'A5', 'B1', 'B2', 'B3', 'B4', 'B5', 'C1', 'C2', 'C3', 'C4', 'C5', 'D1', 'D2', 'D3', 'D4', 'D5', 'E1', 'E2', 'E3', 'E4', 'E5'};
            app.DropDown.Value = 'A1';
            app.NaranjasPanel = uipanel(app.InterfaceTab);
            app.NaranjasPanel.Title = 'Naranjas';
            app.EditField_2 = uieditfield(app.NaranjasPanel, 'text');
            app.CapturasEditFieldLabel = uilabel(app.NaranjasPanel);
            app.CapturasEditFieldLabel.Text = 'Capturas';
            app.CapturasEditField = uieditfield(app.NaranjasPanel, 'numeric');
            app.CapturasEditField.ValueDisplayFormat = '%.0f';
            app.TextArea = uitextarea(app.InterfaceTab);
            app.CapturaButton = uibutton(app.InterfaceTab, 'push');
            app.CapturaButton.ButtonPushedFcn = createCallbackFcn(app, @CapturaButtonPushed, true);
            app.CapturaButton.Text = 'Captura';
            app.EditField_3 = uieditfield(app.InterfaceTab, 'text');
            app.CALIBRATECAMERAButton = uibutton(app.InterfaceTab, 'push');
            app.CALIBRATECAMERAButton.ButtonPushedFcn = createCallbackFcn(app, @CALIBRATECAMERAButtonPushed, true);
            app.CALIBRATECAMERAButton.Text = 'CALIBRATE CAMERA';
            app.ColorDetectionTab = uitab(app.TabGroup);
            app.ColorDetectionTab.Title = 'Color Detection';
            app.UIAxes2 = uiaxes(app.ColorDetectionTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2_2 = uiaxes(app.ColorDetectionTab);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.SerialandCalibrationTab = uitab(app.TabGroup);
            app.SerialandCalibrationTab.Title = 'Serial and Calibration';
            app.ComunicacinPanel = uipanel(app.SerialandCalibrationTab);
            app.ComunicacinPanel.Title = 'Comunicación';
            app.PuertoDropDownLabel = uilabel(app.ComunicacinPanel);
            app.PuertoDropDownLabel.Text = 'Puerto';
            app.Puerto = uidropdown(app.ComunicacinPanel);
            app.Puerto.Items = {};
            app.Puerto.Value = {};
            app.ConectarButton = uibutton(app.ComunicacinPanel, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.DesconectarButton = uibutton(app.ComunicacinPanel, 'push');
            app.DesconectarButton.ButtonPushedFcn = createCallbackFcn(app, @DesconectarButtonPushed, true);
            app.DesconectarButton.Text = 'Desconectar';
            app.RButton = uibutton(app.ComunicacinPanel, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = 'R';
            app.NEXTButton = uibutton(app.SerialandCalibrationTab, 'push');
            app.NEXTButton.Text = 'NEXT';
            app.TextArea_2 = uitextarea(app.SerialandCalibrationTab);
            app.BaudrateDropDownLabel = uilabel(app.SerialandCalibrationTab);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.Baudrate = uidropdown(app.SerialandCalibrationTab);
            app.Baudrate.Items = {};
            app.Baudrate.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Go

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end