classdef Panel_Control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        PaneldecontrolTab              matlab.ui.container.Tab
        HomeButton                     matlab.ui.control.Button
        CadenaabiertaPanel             matlab.ui.container.Panel
        V2                             matlab.ui.control.Slider
        V3                             matlab.ui.control.Slider
        V1                             matlab.ui.control.Slider
        q1mmsLabel                     matlab.ui.control.Label
        q2gradsLabel                   matlab.ui.control.Label
        q3gradsLabel                   matlab.ui.control.Label
        RButton                        matlab.ui.control.Button
        CadenacerradaPanel             matlab.ui.container.Panel
        q1mmLabel                      matlab.ui.control.Label
        q2degLabel                     matlab.ui.control.Label
        q3degLabel                     matlab.ui.control.Label
        posq1                          matlab.ui.control.Slider
        posq2                          matlab.ui.control.Slider
        posq3                          matlab.ui.control.Slider
        posq4                          matlab.ui.control.Slider
        q4degLabel                     matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        ComunicacinPanel               matlab.ui.container.Panel
        PuertoDropDownLabel            matlab.ui.control.Label
        Puerto                         matlab.ui.control.DropDown
        BaudrateDropDownLabel          matlab.ui.control.Label
        Baudrate                       matlab.ui.control.DropDown
        ConectarButton                 matlab.ui.control.Button
        RButton_2                      matlab.ui.control.Button
        DesconectarButton              matlab.ui.control.Button
        PosicinmmPanel                 matlab.ui.container.Panel
        YLabel_3                       matlab.ui.control.Label
        Y                              matlab.ui.control.NumericEditField
        ZLabel_3                       matlab.ui.control.Label
        Z                              matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        X                              matlab.ui.control.NumericEditField
        CadenaSwitch                   matlab.ui.control.ToggleSwitch
        VentosaPanel                   matlab.ui.container.Panel
        SliderVentosa                  matlab.ui.control.Slider
        GarraPanel                     matlab.ui.container.Panel
        AbrirButton                    matlab.ui.control.Button
        CerrarButton                   matlab.ui.control.Button
        MS                             matlab.ui.control.NumericEditField
        msLabel                        matlab.ui.control.Label
        SteppersSwitchLabel            matlab.ui.control.Label
        SteppersSwitch                 matlab.ui.control.Switch
        TrayectoriaPanel               matlab.ui.container.Panel
        XEditFieldLabel_2              matlab.ui.control.Label
        X_Tr                           matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        Y_Tr                           matlab.ui.control.NumericEditField
        ModoDropDownLabel              matlab.ui.control.Label
        ModoDropDown                   matlab.ui.control.DropDown
        EnviarButton                   matlab.ui.control.Button
        PuntosEditFieldLabel           matlab.ui.control.Label
        Puntos                         matlab.ui.control.NumericEditField
        Lamp_Tr                        matlab.ui.control.Lamp
        ZEditFieldLabel                matlab.ui.control.Label
        Z_Tr                           matlab.ui.control.NumericEditField
        TiempototalsEditFieldLabel     matlab.ui.control.Label
        Tiempo                         matlab.ui.control.NumericEditField
        PrecisionmmEditFieldLabel      matlab.ui.control.Label
        PrecisionmmEditField           matlab.ui.control.NumericEditField
        PosicinPanel                   matlab.ui.container.Panel
        q3EditFieldLabel               matlab.ui.control.Label
        q3p                            matlab.ui.control.NumericEditField
        q1mmLabel_2                    matlab.ui.control.Label
        q1p                            matlab.ui.control.NumericEditField
        q2EditFieldLabel               matlab.ui.control.Label
        q2p                            matlab.ui.control.NumericEditField
        CinemticaTab                   matlab.ui.container.Tab
        CinemticadirectaPanel          matlab.ui.container.Panel
        CoordenadasarticularesPanel    matlab.ui.container.Panel
        Q1Label                        matlab.ui.control.Label
        Q1d                            matlab.ui.control.Spinner
        Q2Label                        matlab.ui.control.Label
        Q2d                            matlab.ui.control.Spinner
        Q3Label                        matlab.ui.control.Label
        Q3d                            matlab.ui.control.Spinner
        CoordenadascartesianasPanel    matlab.ui.container.Panel
        ZLabel                         matlab.ui.control.Label
        Zd                             matlab.ui.control.NumericEditField
        YLabel                         matlab.ui.control.Label
        Yd                             matlab.ui.control.NumericEditField
        XLabel                         matlab.ui.control.Label
        Xd                             matlab.ui.control.NumericEditField
        Lamp_D                         matlab.ui.control.Lamp
        CalcularD                      matlab.ui.control.Button
        CinemticainversaPanel          matlab.ui.container.Panel
        CoordenadascartesianasPanel_2  matlab.ui.container.Panel
        YLabel_2                       matlab.ui.control.Label
        Yi_3                           matlab.ui.control.Spinner
        ZLabel_2                       matlab.ui.control.Label
        Zi_3                           matlab.ui.control.Spinner
        XLabel_2                       matlab.ui.control.Label
        Xi_3                           matlab.ui.control.Spinner
        Lamp_I                         matlab.ui.control.Lamp
        CoordenadasarticularesPanel_2  matlab.ui.container.Panel
        Q2Label_2                      matlab.ui.control.Label
        Q2i                            matlab.ui.control.NumericEditField
        Q3Label_2                      matlab.ui.control.Label
        Q3i                            matlab.ui.control.NumericEditField
        Q1Label_2                      matlab.ui.control.Label
        Q1i                            matlab.ui.control.NumericEditField
        CalcularI                      matlab.ui.control.Button
        rdenesTab                      matlab.ui.container.Tab
        ProgramacinPanel               matlab.ui.container.Panel
        ProgramacinTextArea            matlab.ui.control.TextArea
        EnviarOrdenesButton            matlab.ui.control.Button
    end


    properties (Access = public)
        myTimer = 0; % Timer periodico
        arduino = 0; % Puerto serial
        timeS = 0;
        Obarra1A = stlread('Barra2.stl');
        Obarra1B = stlread('Barra.stl');
        Obarra1C = stlread('Barra2.stl');
        Obarra2A = stlread('Barra2.stl');
        Oenlace = stlread('Enlace.stl');
        Obarra2B = stlread('Barra.stl');
        Otriangulo = stlread('Middle.stl');
        Otoolhead = stlread('Toolhead.stl');
        Opatin = stlread('Slider.stl');
        Obase = stlread('Base.stl');
        nowX = 0;
        nowY = 0;
        nowZ = 0;
    end
    
    methods (Access = public)
        
        function main(app)
            
            if app.CadenaSwitch.Value=="Abierta"
                app.V1.Enable = 'on';
                app.V2.Enable = 'on';
                app.V3.Enable = 'on';
                app.q1mmsLabel.Enable = 'on';
                app.q2gradsLabel.Enable = 'on';
                app.q3gradsLabel.Enable = 'on';
                
                
                app.posq1.Enable = 'off';
                app.posq2.Enable = 'off';
                app.posq3.Enable = 'off';
                app.posq4.Enable = 'off';
                app.q1mmLabel.Enable = 'off';
                app.q2degLabel.Enable = 'off';
                app.q3degLabel.Enable = 'off';
                app.q4degLabel.Enable = 'off';
            end
            
            if app.CadenaSwitch.Value=="Cerrada"
                
                
                % Detener movimiento
                try
                    mandarComando(app, sprintf('J2 M%d %3.3f\n', 1, 0)); pause(0.1);
                    mandarComando(app, sprintf('J2 M%d %3.3f\n', 2, 0)); pause(0.1);
                    mandarComando(app, sprintf('J2 M%d %3.3f\n', 3, 0)); pause(0.1);
                catch
                    for i=1:3
                        set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                        pause(0.3);
                        set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                        pause(0.3);
                    end
                end
                app.V1.Enable = 'off';
                app.V2.Enable = 'off';
                app.V3.Enable = 'off';
                app.q1mmsLabel.Enable = 'off';
                app.q2gradsLabel.Enable = 'off';
                app.q3gradsLabel.Enable = 'off';
                app.posq1.Enable = 'on';
                app.posq2.Enable = 'on';
                app.posq3.Enable = 'on';
                app.posq4.Enable = 'on';
                app.q1mmLabel.Enable = 'on';
                app.q2degLabel.Enable = 'on';
                app.q3degLabel.Enable = 'on';
                app.q4degLabel.Enable = 'on';
            end
            
            app.Puntos.Enable =  'on';
            app.PrecisionmmEditField.Enable = 'off';
            app.Tiempo.Enable =  'on';
            
        end
        
        function consultarAngulos(app,~) % Callback para el timer
            timeN = second(datetime('now'));
            
            if timeN - app.timeS < 1
                return;
            end
            
            try
                q1 = consultarPosicion(app,1);
                q2 = consultarPosicion(app,2);
                q3 = consultarPosicion(app,3);
            catch
                disp("ERROR EN COMUNICACION1");
                return;
            end

            
            try
            q2r=deg2rad(q2);
            q3r=deg2rad(q3);

            app.Q1d.Value = q1;
            app.Q2d.Value = deg2rad(q2);
            app.Q3d.Value = deg2rad(q3);

            app.q1p.Value = q1;
            app.q2p.Value = q2;
            app.q3p.Value = q3;

            [x2,y2,z2,x1,y1,z1] = directa(app.Q1d.Value,app.Q2d.Value,app.Q3d.Value);
            app.X.Value = x2;
            app.Y.Value = y2;
            app.Z.Value = z2;

            app.nowX = x2;
            app.nowY = y2;
            app.nowZ = z2;
            catch
                disp("ERROR EN COMUNICACION2");
                return;            
            end

        % PLOT
        cla(app.UIAxes);
        hold(app.UIAxes,'on');
        
        barra1A = app.Obarra1A;
        barra1B = app.Obarra1B;
        barra1C = app.Obarra1C;
        barra2A = app.Obarra2A;
        enlace = app.Oenlace;
        barra2B = app.Obarra2B;
        triangulo = app.Otriangulo;
        toolhead = app.Otoolhead;
        patin = app.Opatin;
        base = app.Obase;


        b01A = barra1A.vertices;
        b01B = barra1B.vertices;
        b01C = barra1C.vertices;
        b02A = barra2A.vertices;
        enl = enlace.vertices;
        b02B = barra2B.vertices;
        t = triangulo.vertices;
        th = toolhead.vertices;
        p = patin.vertices;
        ba = base.vertices;


        view(app.UIAxes, 3)
        
        %% Barra 1 A (doble)
        % Rotacion del brazo
        G = [1 0 0; 0 cos(q2r) -sin(q2r); 0 sin(q2r) cos(q2r)];
        % Posicionamiento y traslacion
        T = [q1-29 0 0];
        
        barra1A.vertices = (G*b01A')'+ T;
        
        patch(app.UIAxes, barra1A,'FaceColor',[0.8 0.8 1.0],'EdgeColor','none','FaceLighting','gouraud','AmbientStrength', 0.15);
        material('metal');
        
        %% Barra 1 B (simple)
        % Rotacion del brazo
        G = [1 0 0; 0 cos(q2r) -sin(q2r); 0 sin(q2r) cos(q2r)];
        % Posicionamiento y traslacion
        T = [(q1-5) -50 50];
        
        barra1B.vertices = (G*b01B')'+ T;
        
        patch(app.UIAxes, barra1B,'FaceColor',[0.8 0.8 1.0],'EdgeColor','none','FaceLighting','gouraud','AmbientStrength', 0.15);
        material('metal');
        
        %% Barra 1 C (Doble)
        % Rotacion del brazo
        G = [1 0 0; 0 cos(q2r) -sin(q2r); 0 sin(q2r) cos(q2r)];
        % Posicionamiento y traslacion
        T = [q1-29 (40*cos(pi+q3r)) (40*sin(pi+q3r))];
        
        barra1C.vertices = (G*b01C')'+ T;
        
        patch(app.UIAxes, barra1C,'FaceColor',[0.8 0.8 1.0],'EdgeColor','none','FaceLighting','gouraud','AmbientStrength', 0.15);
        material('metal');
        
        %% Barra 2 A (Doble)
        % Rotacion del brazo
        G = [1 0 0; 0 cos(q3r) -sin(q3r); 0 sin(q3r) cos(q3r)];
        % Posicionamiento y traslacion
        T = [q1-29 (cos(q2r)*300) (sin(q2r)*300)];
        
        barra2A.vertices = (G*b02A')'+ T;
        
        patch(app.UIAxes, barra2A,'FaceColor', [0.8 0.8 1.0],'EdgeColor','none','FaceLighting','gouraud','AmbientStrength', 0.15);
        material('metal');
        %% Enlace (Doble)
        % Rotacion del enlace
        G = [1 0 0; 0 cos(pi+q3r) -sin(pi+q3r); 0 sin(pi+q3r) cos(pi+q3r)];
        % Posicionamiento y traslacion
        T = [q1-29 (cos(q2r)*300-10*sin(q3r)) (sin(q2r)*300+10*cos(q3r))];
        
        enlace.vertices = (G*enl')'+ T;
        
        patch(app.UIAxes, enlace,'FaceColor',[0.8500, 0.3250, 0.0980],'EdgeColor','none','FaceLighting','gouraud','AmbientStrength', 0.15);
        material('metal');
        
        
        %% Barra 2 B (Simple)
        % Rotacion del brazo
        G = [1 0 0; 0 cos(q3r) -sin(q3r); 0 sin(q3r) cos(q3r)];
        % Posicionamiento y traslacion
        T = [(q1-5) (cos(q2r)*300+50) (sin(q2r)*300+50)];
        
        barra2B.vertices = (G*b02B')'+ T;
        
        patch(app.UIAxes, barra2B, 'FaceColor',[0.8 0.8 1.0],'EdgeColor','none','FaceLighting','gouraud','AmbientStrength', 0.15);
        material('metal');
        
        %% Triangulo
        % Ajuste ejes pieza
        G = [0 0 1; 1 0 0; 0 1 0];
        % Posicionamiento y traslacion
        T = [(q1-17) (cos(q2r)*300-56) (sin(q2r)*300)];
        
        triangulo.vertices = (G*t')'+ T;
        
        patch(app.UIAxes, triangulo,'FaceColor',    [0.8500, 0.3250, 0.0980], ...
            'EdgeColor',       'none',        ...
            'FaceLighting',    'gouraud',     ...
            'AmbientStrength', 0.15);
        material('metal');
        
        %% Toolhead
        % Ajuste ejes pieza
        G = [1 0 0; 0 cos(90*pi/180) -sin(90*pi/180); 0 sin(90*pi/180) cos(90*pi/180)];
        % Posicionamiento y traslacion
        T = [(q1-32) (cos(q2r)*300+cos(q3r)*300+75) (sin(q3r)*300+sin(q2r)*300-10)];
        
        toolhead.vertices = (G*th')'+ T;
        
        patch(app.UIAxes, toolhead,'FaceColor',    [0.8500, 0.3250, 0.0980], ...
            'EdgeColor',       'none',        ...
            'FaceLighting',    'gouraud',     ...
            'AmbientStrength', 0.15);
        material('metal');
        
        %% Patin
        % Ajuste ejes pieza
        G = [1 0 0; 0 0 -1; 0 1 0];
        % Posicionamiento y traslacion
        T = [(q1-185/2) 20 -120];
        
        patin.vertices = (G*p')'+ T;
        
        patch(app.UIAxes, patin,'FaceColor',  [0.8500, 0.3250, 0.0980],'EdgeColor','none','FaceLighting','gouraud','AmbientStrength', 0.15);
        material('metal');
        
        %% Base
        % Ajuste ejes pieza
        G = [1 0 0; 0 0 -1; 0 1 0];
        % Posicionamiento y traslacion
        T = [(-250/2) 380 -145];
        
        base.vertices = (G*ba')'+ T;
        
        patch(app.UIAxes, base,'FaceColor',[1 0.8 0.6],'EdgeColor','none','FaceLighting','gouraud','AmbientStrength', 0.15);
        material('metal');
        
        %% Dibujo
        camlight('headlight');
        grid on
        axis equal
        
        %% Titulos
        xlabel(app.UIAxes, 'x(mm)');
        ylabel(app.UIAxes, 'y(mm)');
        zlabel(app.UIAxes, 'z(mm)');
        
        hold(app.UIAxes,'off');
        hold(app.UIAxes,'off');
        % PLOT


%             % PLOT ANTIGUO
%             cla(app.UIAxes);
%             hold(app.UIAxes,'on');
%             
%             plot3(app.UIAxes,[x1 x1 x2] ,[0 y1 y2] ,[0 z1 z2],'k');
%             set(findall(gca, 'Type', 'Line'),'LineWidth',3);
%             
%             quiver3(app.UIAxes,0,0,0,50,0,0,'r');
%             quiver3(app.UIAxes,0,0,0,0,50,0,'g');
%             quiver3(app.UIAxes,0,0,0,0,0,50,'b');
%             
%             quiver3(app.UIAxes,x1,0,0,50,0,0,'r');
%             quiver3(app.UIAxes,x1,0,0,0,50,0,'g');
%             quiver3(app.UIAxes,x1,0,0,0,0,50,'b');
%             
%             G = [1 0 0; 0 cosd(q2) -sind(q2); 0 sind(q2) cosd(q2)];
%             o1 = G*eye(3)*50;
%             quiver3(app.UIAxes,x1,y1,z1,o1(1,1),o1(2,1),o1(3,1),'r');
%             quiver3(app.UIAxes,x1,y1,z1,o1(1,2),o1(2,2),o1(3,2),'g');
%             quiver3(app.UIAxes,x1,y1,z1,o1(1,3),o1(2,3),o1(3,3),'b');
%             
%             G = [1 0 0; 0 cosd(q3) -sind(q3); 0 sind(q3) cosd(q3)];
%             o2 = G*eye(3)*50;
%             quiver3(app.UIAxes,x2,y2,z2,o2(1,1),o2(2,1),o2(3,1),'r');
%             quiver3(app.UIAxes,x2,y2,z2,o2(1,2),o2(2,2),o2(3,2),'g');
%             quiver3(app.UIAxes,x2,y2,z2,o2(1,3),o2(2,3),o2(3,3),'b');
%             
%             hold(app.UIAxes,'off');
            % PLOT          
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Puerto.Items=seriallist;
            app.Baudrate.Items = {'300', '600', '1200', '2400', '4800', '9600', '14400', '19200', '28800', '38400', '57600', '115200'};
            app.Baudrate.Value = {'9600'};
            main(app);
        end

        % Button pushed function: CalcularI
        function CalcularIPushed(app, event)
            x=[app.Xi.Value app.Yi.Value app.Zi.Value];
            if constrains(x)==1
                app.Lamp_Tr.Color='Green';
                [app.Q1i.Value,app.Q2i.Value,app.Q3i.Value] = inversa(app.Xi.Value, app.Yi.Value, app.Zi.Value);
            else
                app.Lamp_Tr.Color='Red';
            end
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            try
                mandarComando(app,'J0');
                set(app.HomeButton,'BackgroundColor',[0.6 1 0.6]);
            catch
                for i=1:3
                    set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                    pause(0.3);
                    set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                    pause(0.3);
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Detener timer
            try
                stop(app.myTimer);
                delete(app.myTimer);
            catch
            end
            % Detener conexion serie
            try
                fclose(app.arduino);
                delete(app.UIFigure);
            catch
                delete(app.UIFigure);
            end
            
        end

        % Value changed function: Baudrate, Puerto
        function PuertoValueChanged(app, event)
            try
                app.arduino=serial(app.Puerto.Value,'BaudRate', str2double(app.Baudrate.Value));
                fopen(app.arduino);
                set(app.ConectarButton,'BackgroundColor',[0.6 1 0.6]);
            catch
                for i=1:3
                    set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                    pause(0.3);
                    set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                    pause(0.3);
                end
            end
        end

        % Value changed function: SteppersSwitch
        function SteppersSwitchValueChanged(app, event)
            try
                mandarComando(app,'J3');
            catch
                for i=1:3
                    set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                    pause(0.3);
                    set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                    pause(0.3);
                end
            end
            
        end

        % Value changed function: V2
        function V2ValueChanged(app, event)
            try
                mandarComando(app, sprintf('J2 M%d %f\n', 2, app.V2.Value));
            catch
                for i=1:3
                    set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                    pause(0.3);
                    set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                    pause(0.3);
                end
            end
        end

        % Value changed function: V3
        function V3ValueChanged(app, event)
            try
                mandarComando(app, sprintf('J2 M%d %f\n', 3,app.V3.Value));
            catch
                for i=1:3
                    set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                    pause(0.3);
                    set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                    pause(0.3);
                end
            end
        end

        % Value changed function: V1
        function V1ValueChanged(app, event)
            try
                mandarComando(app, sprintf('J2 M%d %f\n', 1, app.V1.Value));
            catch
                for i=1:3
                    set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                    pause(0.3);
                    set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                    pause(0.3);
                end
            end
        end

        % Value changed function: CadenaSwitch
        function CadenaSwitchValueChanged(app, event)
            main(app);
            
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            delete(instrfindall);
            
            try
                app.arduino=serial(app.Puerto.Value,'BaudRate',str2double(app.Baudrate.Value));
                fopen(app.arduino);
                set(app.ConectarButton,'BackgroundColor',[0.6 1 0.6]);
                set(app.DesconectarButton,'BackgroundColor',[0.96,0.96,0.96]);
                pause(2);
                
            catch
                for i=1:3
                    set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                    pause(0.3);
                    set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                    pause(0.3);
                end
            end
            
            try
                app.myTimer = timer('TimerFcn', @(~,~) app.consultarAngulos, ...
                    'ExecutionMode', 'fixedRate');
                app.myTimer.Period = 0.1;
                start(app.myTimer)
            catch
            end
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            app.V1.Value=0;
            app.V2.Value=0;
            app.V3.Value=0;
            app.posq1.Value=100;
            app.posq2.Value=55;
            app.posq3.Value=-50;
            
            try
                mandarComando(app, sprintf('J2 M%d %3.3f\n', 2, app.V2.Value)); pause(0.1);
                mandarComando(app, sprintf('J2 M%d %3.3f\n', 1, app.V1.Value)); pause(0.1);
                mandarComando(app, sprintf('J2 M%d %3.3f\n', 3, app.V3.Value)); pause(0.1);
            catch
                for i=1:3
                    set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                    pause(0.3);
                    set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                    pause(0.3);
                end
            end
            app.SliderVentosa.Value=0;
        end

        % Button pushed function: RButton_2
        function RButton_2Pushed(app, event)
            app.Puerto.Items=seriallist;
        end

        % Button pushed function: DesconectarButton
        function DesconectarButtonPushed(app, event)
            try
                fclose(app.arduino);
                set(app.DesconectarButton,'BackgroundColor',[0.6 1 0.6]);
                set(app.ConectarButton,'BackgroundColor',[0.96,0.96,0.96]);
                set(app.HomeButton,'BackgroundColor','red');
                
            catch
            end
        end

        % Value changed function: SliderVentosa
        function SliderVentosaValueChanged(app, event)
            try
                mandarComando(app, sprintf('J4 M%d %3.3f\n', 2, app.SliderVentosa.Value));
            catch
                for i=1:3
                    set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                    pause(0.3);
                    set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                    pause(0.3);
                end
            end
        end

        % Button pushed function: AbrirButton
        function AbrirButtonPushed(app, event)
            try
                mandarComando(app, sprintf('J4 M%d %3.3f\n', 1, app.MS.Value));
            catch
                for i=1:3
                    set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                    pause(0.3);
                    set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                    pause(0.3);
                end
            end
            
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
            try
                mandarComando(app, sprintf('J4 M%d %3.3f\n', 1, -app.MS.Value));
            catch
                for i=1:3
                    set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                    pause(0.3);
                    set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                    pause(0.3);
                end
            end
            
        end

        % Value changed function: posq1
        function posq1ValueChanged(app, event)
            if app.CadenaSwitch.Value=="Cerrada"
                try
                    mandarComando(app, sprintf('J1 M%d %3.3f\n', 1, app.posq1.Value));
                catch
                    for i=1:3
                        set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                        pause(0.3);
                        set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                        pause(0.3);
                    end
                end
            end
        end

        % Value changed function: posq2
        function posq2ValueChanged(app, event)
            if app.CadenaSwitch.Value=="Cerrada"
                try
                    mandarComando(app, sprintf('J1 M%d %3.3f\n', 2, app.posq2.Value));
                catch
                    for i=1:3
                        set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                        pause(0.3);
                        set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                        pause(0.3);
                    end
                end
            end
        end

        % Value changed function: posq3
        function posq3ValueChanged(app, event)
            if app.CadenaSwitch.Value=="Cerrada"
                try
                    mandarComando(app, sprintf('J1 M%d %3.3f\n', 3, app.posq3.Value));
                catch
                    for i=1:3
                        set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                        pause(0.3);
                        set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                        pause(0.3);
                    end
                end
            end
        end

        % Button pushed function: EnviarButton
        function EnviarButtonPushed(app, event)
            try
            x=[app.X_Tr.Value app.Y_Tr.Value app.Z_Tr.Value];
                if constrains(x) == 1
                    app.Lamp_Tr.Color = 'Green';
                    x0 = [app.nowX app.nowY app.nowZ];
                    disp("MANDANDO COMANDO");
                    if app.ModoDropDown.Value == "Interpolador Cartesiano"
                        moveCartesianas(app,x0,x,app.Tiempo.Value,app.Puntos.Value);
                        app.timeS = second(datetime('now'));
                    end
                
                    if app.ModoDropDown.Value == "Jacobiana"
                        moveJacobiana(app,app.Puntos.Value,[app.X.Value app.Y.Value app.Z.Value],x);
                    end
                             
                else
                    app.Lamp_Tr.Color='Red';
                end
                
            catch
                    
                    for i=1:3
                        set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                        pause(0.3);
                        set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                        pause(0.3);
                    end 
            end
            
        end

        % Value changed function: ModoDropDown
        function ModoDropDownValueChanged(app, event)
            
            if app.ModoDropDown.Value=="Interpolador Cartesiano"
                app.PuntosEditFieldLabel.Text = 'Puntos';
                app.PrecisionmmEditField.Enable = 'off';
                app.Tiempo.Enable =  'on';
            end
            
            if app.ModoDropDown.Value=="Interpolador Articular"
                app.PuntosEditFieldLabel.Text = 'Velocidad';
                app.PrecisionmmEditField.Enable =  'on';
                app.Tiempo.Enable =  'off';
            end
            
            if app.ModoDropDown.Value=="Jacobiana"
                app.PuntosEditFieldLabel.Text = 'Velocidad';
                app.PrecisionmmEditField.Enable =  'on';
                app.Tiempo.Enable =  'off';
            end

            
        end

        % Value changed function: posq4
        function posq4ValueChanged(app, event)
           if app.CadenaSwitch.Value=="Cerrada"
                try
                    mandarComando(app, sprintf('J1 M%d %3.3f\n', 4, app.posq4.Value));
                catch
                    for i=1:3
                        set(app.ConectarButton,'BackgroundColor',[1 0 0]);
                        pause(0.3);
                        set(app.ConectarButton,'BackgroundColor',[0.96 0.96 0.96]);
                        pause(0.3);
                    end
                end
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PaneldecontrolTab = uitab(app.TabGroup);
            app.PaneldecontrolTab.Title = 'Panel de control';
            app.HomeButton = uibutton(app.PaneldecontrolTab, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.CadenaabiertaPanel = uipanel(app.PaneldecontrolTab);
            app.CadenaabiertaPanel.Title = 'Cadena abierta';
            app.V2 = uislider(app.CadenaabiertaPanel);
            app.V2.Limits = [-10 10];
            app.V2.ValueChangedFcn = createCallbackFcn(app, @V2ValueChanged, true);
            app.V2.Enable = 'off';
            app.V3 = uislider(app.CadenaabiertaPanel);
            app.V3.Limits = [-10 10];
            app.V3.ValueChangedFcn = createCallbackFcn(app, @V3ValueChanged, true);
            app.V3.Enable = 'off';
            app.V1 = uislider(app.CadenaabiertaPanel);
            app.V1.Limits = [-30 30];
            app.V1.ValueChangedFcn = createCallbackFcn(app, @V1ValueChanged, true);
            app.V1.Enable = 'off';
            app.q1mmsLabel = uilabel(app.CadenaabiertaPanel);
            app.q1mmsLabel.Enable = 'off';
            app.q1mmsLabel.Text = 'q1 (mm/s)';
            app.q2gradsLabel = uilabel(app.CadenaabiertaPanel);
            app.q2gradsLabel.Enable = 'off';
            app.q2gradsLabel.Text = 'q2 (grad/s)';
            app.q3gradsLabel = uilabel(app.CadenaabiertaPanel);
            app.q3gradsLabel.Enable = 'off';
            app.q3gradsLabel.Text = 'q3 (grad/s)';
            app.RButton = uibutton(app.CadenaabiertaPanel, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = 'R';
            app.CadenacerradaPanel = uipanel(app.PaneldecontrolTab);
            app.CadenacerradaPanel.Title = 'Cadena cerrada';
            app.q1mmLabel = uilabel(app.CadenacerradaPanel);
            app.q1mmLabel.Enable = 'off';
            app.q1mmLabel.Text = 'q1 (mm)';
            app.q2degLabel = uilabel(app.CadenacerradaPanel);
            app.q2degLabel.Enable = 'off';
            app.q2degLabel.Text = 'q2 (deg)';
            app.q3degLabel = uilabel(app.CadenacerradaPanel);
            app.q3degLabel.Enable = 'off';
            app.q3degLabel.Text = 'q3 (deg)';
            app.posq1 = uislider(app.CadenacerradaPanel);
            app.posq1.Limits = [0 200];
            app.posq1.ValueChangedFcn = createCallbackFcn(app, @posq1ValueChanged, true);
            app.posq1.Enable = 'off';
            app.posq2 = uislider(app.CadenacerradaPanel);
            app.posq2.Limits = [20 90];
            app.posq2.ValueChangedFcn = createCallbackFcn(app, @posq2ValueChanged, true);
            app.posq2.Enable = 'off';
            app.posq2.Value = 20;
            app.posq3 = uislider(app.CadenacerradaPanel);
            app.posq3.Limits = [-120 10];
            app.posq3.ValueChangedFcn = createCallbackFcn(app, @posq3ValueChanged, true);
            app.posq3.Enable = 'off';
            app.posq4 = uislider(app.CadenacerradaPanel);
            app.posq4.Limits = [0 180];
            app.posq4.ValueChangedFcn = createCallbackFcn(app, @posq4ValueChanged, true);
            app.posq4.Enable = 'off';
            app.q4degLabel = uilabel(app.CadenacerradaPanel);
            app.q4degLabel.Enable = 'off';
            app.q4degLabel.Text = 'q4 (deg)';
            app.UIAxes = uiaxes(app.PaneldecontrolTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-70 400];
            app.UIAxes.YLim = [-200 600];
            app.UIAxes.ZLim = [-250 400];
            app.ComunicacinPanel = uipanel(app.PaneldecontrolTab);
            app.ComunicacinPanel.Title = 'Comunicación';
            app.PuertoDropDownLabel = uilabel(app.ComunicacinPanel);
            app.PuertoDropDownLabel.Text = 'Puerto';
            app.Puerto = uidropdown(app.ComunicacinPanel);
            app.Puerto.Items = {};
            app.Puerto.ValueChangedFcn = createCallbackFcn(app, @PuertoValueChanged, true);
            app.Puerto.Value = {};
            app.BaudrateDropDownLabel = uilabel(app.ComunicacinPanel);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.Baudrate = uidropdown(app.ComunicacinPanel);
            app.Baudrate.Items = {};
            app.Baudrate.ValueChangedFcn = createCallbackFcn(app, @PuertoValueChanged, true);
            app.Baudrate.Value = {};
            app.ConectarButton = uibutton(app.ComunicacinPanel, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.RButton_2 = uibutton(app.ComunicacinPanel, 'push');
            app.RButton_2.ButtonPushedFcn = createCallbackFcn(app, @RButton_2Pushed, true);
            app.RButton_2.Text = 'R';
            app.DesconectarButton = uibutton(app.ComunicacinPanel, 'push');
            app.DesconectarButton.ButtonPushedFcn = createCallbackFcn(app, @DesconectarButtonPushed, true);
            app.DesconectarButton.Text = 'Desconectar';
            app.PosicinmmPanel = uipanel(app.PaneldecontrolTab);
            app.PosicinmmPanel.Title = 'Posición (mm)';
            app.YLabel_3 = uilabel(app.PosicinmmPanel);
            app.YLabel_3.Text = 'Y';
            app.Y = uieditfield(app.PosicinmmPanel, 'numeric');
            app.Y.Editable = 'off';
            app.ZLabel_3 = uilabel(app.PosicinmmPanel);
            app.ZLabel_3.Text = 'Z';
            app.Z = uieditfield(app.PosicinmmPanel, 'numeric');
            app.Z.Editable = 'off';
            app.XEditFieldLabel = uilabel(app.PosicinmmPanel);
            app.XEditFieldLabel.Text = 'X';
            app.X = uieditfield(app.PosicinmmPanel, 'numeric');
            app.X.Editable = 'off';
            app.CadenaSwitch = uiswitch(app.PaneldecontrolTab, 'toggle');
            app.CadenaSwitch.Items = {'Cerrada', 'Abierta'};
            app.CadenaSwitch.ValueChangedFcn = createCallbackFcn(app, @CadenaSwitchValueChanged, true);
            app.CadenaSwitch.Value = 'Abierta';
            app.VentosaPanel = uipanel(app.PaneldecontrolTab);
            app.VentosaPanel.Title = 'Ventosa';
            app.SliderVentosa = uislider(app.VentosaPanel);
            app.SliderVentosa.Limits = [0 255];
            app.SliderVentosa.ValueChangedFcn = createCallbackFcn(app, @SliderVentosaValueChanged, true);
            app.GarraPanel = uipanel(app.PaneldecontrolTab);
            app.GarraPanel.Title = 'Garra';
            app.AbrirButton = uibutton(app.GarraPanel, 'push');
            app.AbrirButton.ButtonPushedFcn = createCallbackFcn(app, @AbrirButtonPushed, true);
            app.AbrirButton.Text = 'Abrir';
            app.CerrarButton = uibutton(app.GarraPanel, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            app.MS = uieditfield(app.GarraPanel, 'numeric');
            app.MS.Value = 100;
            app.msLabel = uilabel(app.GarraPanel);
            app.msLabel.Text = 'ms';
            app.SteppersSwitchLabel = uilabel(app.PaneldecontrolTab);
            app.SteppersSwitchLabel.Text = 'Steppers';
            app.SteppersSwitch = uiswitch(app.PaneldecontrolTab, 'slider');
            app.SteppersSwitch.ValueChangedFcn = createCallbackFcn(app, @SteppersSwitchValueChanged, true);
            app.SteppersSwitch.Value = 'On';
            app.TrayectoriaPanel = uipanel(app.PaneldecontrolTab);
            app.TrayectoriaPanel.Title = 'Trayectoria';
            app.XEditFieldLabel_2 = uilabel(app.TrayectoriaPanel);
            app.XEditFieldLabel_2.Text = 'X';
            app.X_Tr = uieditfield(app.TrayectoriaPanel, 'numeric');
            app.X_Tr.ValueDisplayFormat = '%.0f';
            app.X_Tr.Value = 100;
            app.YEditFieldLabel = uilabel(app.TrayectoriaPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.Y_Tr = uieditfield(app.TrayectoriaPanel, 'numeric');
            app.Y_Tr.ValueDisplayFormat = '%.0f';
            app.Y_Tr.Value = 400;
            app.ModoDropDownLabel = uilabel(app.TrayectoriaPanel);
            app.ModoDropDownLabel.Text = 'Modo';
            app.ModoDropDown = uidropdown(app.TrayectoriaPanel);
            app.ModoDropDown.Items = {'Interpolador Cartesiano', 'Jacobiana'};
            app.ModoDropDown.ValueChangedFcn = createCallbackFcn(app, @ModoDropDownValueChanged, true);
            app.ModoDropDown.Value = 'Interpolador Cartesiano';
            app.EnviarButton = uibutton(app.TrayectoriaPanel, 'push');
            app.EnviarButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarButtonPushed, true);
            app.EnviarButton.Text = 'Enviar';
            app.PuntosEditFieldLabel = uilabel(app.TrayectoriaPanel);
            app.PuntosEditFieldLabel.Text = 'Puntos';
            app.Puntos = uieditfield(app.TrayectoriaPanel, 'numeric');
            app.Puntos.ValueDisplayFormat = '%.0f';
            app.Puntos.Value = 4;
            app.Lamp_Tr = uilamp(app.TrayectoriaPanel);
            app.ZEditFieldLabel = uilabel(app.TrayectoriaPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.Z_Tr = uieditfield(app.TrayectoriaPanel, 'numeric');
            app.Z_Tr.ValueDisplayFormat = '%.0f';
            app.Z_Tr.Value = 100;
            app.TiempototalsEditFieldLabel = uilabel(app.TrayectoriaPanel);
            app.TiempototalsEditFieldLabel.Text = 'Tiempo total (s)';
            app.Tiempo = uieditfield(app.TrayectoriaPanel, 'numeric');
            app.Tiempo.ValueDisplayFormat = '%.0f';
            app.Tiempo.Value = 5;
            app.PrecisionmmEditFieldLabel = uilabel(app.TrayectoriaPanel);
            app.PrecisionmmEditFieldLabel.Text = 'Precision (mm)';
            app.PrecisionmmEditField = uieditfield(app.TrayectoriaPanel, 'numeric');
            app.PrecisionmmEditField.ValueDisplayFormat = '%.0f';
            app.PrecisionmmEditField.Value = 1;
            app.PosicinPanel = uipanel(app.PaneldecontrolTab);
            app.PosicinPanel.Title = 'Posición (º)';
            app.q3EditFieldLabel = uilabel(app.PosicinPanel);
            app.q3EditFieldLabel.Text = 'q3';
            app.q3p = uieditfield(app.PosicinPanel, 'numeric');
            app.q3p.Editable = 'off';
            app.q1mmLabel_2 = uilabel(app.PosicinPanel);
            app.q1mmLabel_2.Text = 'q1(mm)';
            app.q1p = uieditfield(app.PosicinPanel, 'numeric');
            app.q1p.Editable = 'off';
            app.q2EditFieldLabel = uilabel(app.PosicinPanel);
            app.q2EditFieldLabel.Text = 'q2';
            app.q2p = uieditfield(app.PosicinPanel, 'numeric');
            app.q2p.Editable = 'off';
            app.CinemticaTab = uitab(app.TabGroup);
            app.CinemticaTab.Title = 'Cinemática';
            app.CinemticadirectaPanel = uipanel(app.CinemticaTab);
            app.CinemticadirectaPanel.Title = 'Cinemática directa';
            app.CoordenadasarticularesPanel = uipanel(app.CinemticadirectaPanel);
            app.CoordenadasarticularesPanel.Title = 'Coordenadas articulares';
            app.Q1Label = uilabel(app.CoordenadasarticularesPanel);
            app.Q1Label.Text = 'Q1';
            app.Q1d = uispinner(app.CoordenadasarticularesPanel);
            app.Q2Label = uilabel(app.CoordenadasarticularesPanel);
            app.Q2Label.Text = 'Q2';
            app.Q2d = uispinner(app.CoordenadasarticularesPanel);
            app.Q2d.Value = 1.5;
            app.Q3Label = uilabel(app.CoordenadasarticularesPanel);
            app.Q3Label.Text = 'Q3';
            app.Q3d = uispinner(app.CoordenadasarticularesPanel);
            app.Q3d.Value = -0.29;
            app.CoordenadascartesianasPanel = uipanel(app.CinemticadirectaPanel);
            app.CoordenadascartesianasPanel.Title = 'Coordenadas cartesianas';
            app.ZLabel = uilabel(app.CoordenadascartesianasPanel);
            app.ZLabel.Text = 'Z';
            app.Zd = uieditfield(app.CoordenadascartesianasPanel, 'numeric');
            app.Zd.Editable = 'off';
            app.YLabel = uilabel(app.CoordenadascartesianasPanel);
            app.YLabel.Text = 'Y';
            app.Yd = uieditfield(app.CoordenadascartesianasPanel, 'numeric');
            app.Yd.Editable = 'off';
            app.XLabel = uilabel(app.CoordenadascartesianasPanel);
            app.XLabel.Text = 'X';
            app.Xd = uieditfield(app.CoordenadascartesianasPanel, 'numeric');
            app.Xd.Editable = 'off';
            app.Lamp_D = uilamp(app.CinemticadirectaPanel);
            app.CalcularD = uibutton(app.CinemticadirectaPanel, 'push');
            app.CalcularD.Text = 'Calcular';
            app.CinemticainversaPanel = uipanel(app.CinemticaTab);
            app.CinemticainversaPanel.Title = 'Cinemática inversa';
            app.CoordenadascartesianasPanel_2 = uipanel(app.CinemticainversaPanel);
            app.CoordenadascartesianasPanel_2.Title = 'Coordenadas cartesianas';
            app.YLabel_2 = uilabel(app.CoordenadascartesianasPanel_2);
            app.YLabel_2.Text = 'Y';
            app.Yi_3 = uispinner(app.CoordenadascartesianasPanel_2);
            app.ZLabel_2 = uilabel(app.CoordenadascartesianasPanel_2);
            app.ZLabel_2.Text = 'Z';
            app.Zi_3 = uispinner(app.CoordenadascartesianasPanel_2);
            app.XLabel_2 = uilabel(app.CoordenadascartesianasPanel_2);
            app.XLabel_2.Text = 'X';
            app.Xi_3 = uispinner(app.CoordenadascartesianasPanel_2);
            app.Lamp_I = uilamp(app.CoordenadascartesianasPanel_2);
            app.CoordenadasarticularesPanel_2 = uipanel(app.CinemticainversaPanel);
            app.CoordenadasarticularesPanel_2.Title = 'Coordenadas articulares';
            app.Q2Label_2 = uilabel(app.CoordenadasarticularesPanel_2);
            app.Q2Label_2.Text = 'Q2';
            app.Q2i = uieditfield(app.CoordenadasarticularesPanel_2, 'numeric');
            app.Q2i.Editable = 'off';
            app.Q3Label_2 = uilabel(app.CoordenadasarticularesPanel_2);
            app.Q3Label_2.Text = 'Q3';
            app.Q3i = uieditfield(app.CoordenadasarticularesPanel_2, 'numeric');
            app.Q3i.Editable = 'off';
            app.Q1Label_2 = uilabel(app.CoordenadasarticularesPanel_2);
            app.Q1Label_2.Text = 'Q1';
            app.Q1i = uieditfield(app.CoordenadasarticularesPanel_2, 'numeric');
            app.Q1i.Editable = 'off';
            app.CalcularI = uibutton(app.CinemticainversaPanel, 'push');
            app.CalcularI.ButtonPushedFcn = createCallbackFcn(app, @CalcularIPushed, true);
            app.CalcularI.Text = 'Calcular';
            app.rdenesTab = uitab(app.TabGroup);
            app.rdenesTab.Title = 'Órdenes';
            app.ProgramacinPanel = uipanel(app.rdenesTab);
            app.ProgramacinPanel.Title = 'Programación';
            app.ProgramacinTextArea = uitextarea(app.ProgramacinPanel);
            app.EnviarOrdenesButton = uibutton(app.rdenesTab, 'push');
            app.EnviarOrdenesButton.Text = 'Enviar Órdenes';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Panel_Control

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end